classdef SqueakPeekStudio < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SqueakPeekStudioUIFigure       matlab.ui.Figure
        ButtonsMenuPanel               matlab.ui.container.Panel
        ButtonsMenuGrid                matlab.ui.container.GridLayout
        InfoButton                     matlab.ui.control.Button
        SettingsButton                 matlab.ui.control.Button
        ExportButton                   matlab.ui.control.Button
        VideoSyncButton                matlab.ui.control.Button
        LabelEditButton                matlab.ui.control.Button
        DetectionButton                matlab.ui.control.Button
        VisualizationButton            matlab.ui.control.Button
        DataInputButton                matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        DataInputTab                   matlab.ui.container.Tab
        DataInputPanel                 matlab.ui.container.Panel
        DataInputGrid                  matlab.ui.container.GridLayout
        BatchModePanel                 matlab.ui.container.Panel
        BatchModeGridGrid              matlab.ui.container.GridLayout
        SelectUSVwavEditFieldBatch     matlab.ui.control.EditField
        SelectVideomp4EditFieldBatch   matlab.ui.control.EditField
        SelectReferenceLabeltxtEditFieldBatch  matlab.ui.control.EditField
        SelectLabeltxtEditFieldBatch   matlab.ui.control.EditField
        SelectVideomp4ButtonBatch      matlab.ui.control.Button
        SelectReferenceLabeltxtButtonBatch  matlab.ui.control.Button
        SelectLabeltxtButtonBatch      matlab.ui.control.Button
        SelectUSVButtonBatch           matlab.ui.control.Button
        SingleModePanel                matlab.ui.container.Panel
        SingleModeGridGrid             matlab.ui.container.GridLayout
        SelectReferenceLabeltxtButtonSingle  matlab.ui.control.Button
        SelectVideomp4ButtonSingle     matlab.ui.control.Button
        SelectVideomp4EditFieldSingle  matlab.ui.control.EditField
        SelectReferenceLabeltxtEditFieldSingle  matlab.ui.control.EditField
        SelectLabeltxtEditFieldSingle  matlab.ui.control.EditField
        SelectLabeltxtButtonSingle     matlab.ui.control.Button
        SelectUSVwavEditFieldSingle    matlab.ui.control.EditField
        SelectUSVButtonSingle          matlab.ui.control.Button
        SingleBatchModeButton          matlab.ui.control.Button
        LoadFilesButton                matlab.ui.control.Button
        VisualizationTab               matlab.ui.container.Tab
        VisualizationPanel             matlab.ui.container.Panel
        VisualizationGrid              matlab.ui.container.GridLayout
        PlotButton                     matlab.ui.control.Button
        SegmentLengthsEditField        matlab.ui.control.NumericEditField
        SegmentLengthsEditFieldLabel   matlab.ui.control.Label
        SegmentStartsEditField         matlab.ui.control.NumericEditField
        SegmentStartsEditFieldLabel    matlab.ui.control.Label
        SonifyButton                   matlab.ui.control.Button
        Button_2                       matlab.ui.control.Button
        Button                         matlab.ui.control.Button
        SpectrogramPlot                matlab.ui.container.Panel
        SingleModeGridGrid_3           matlab.ui.container.GridLayout
        UIAxes2                        matlab.ui.control.UIAxes
        WaveformPlot                   matlab.ui.container.Panel
        SingleModeGridGrid_2           matlab.ui.container.GridLayout
        UIAxes                         matlab.ui.control.UIAxes
        DetectionTab                   matlab.ui.container.Tab
        DetectionPanel                 matlab.ui.container.Panel
        DetectionGrid                  matlab.ui.container.GridLayout
        RunDetectorButton              matlab.ui.control.Button
        LabelEditTab                   matlab.ui.container.Tab
        LabelEditPanel                 matlab.ui.container.Panel
        LabelEditGrid                  matlab.ui.container.GridLayout
        VideoSyncTab                   matlab.ui.container.Tab
        VideoSyncPanel                 matlab.ui.container.Panel
        VideoSyncGrid                  matlab.ui.container.GridLayout
        VideoSyncLabel                 matlab.ui.control.Label
        ExportTab                      matlab.ui.container.Tab
        ExportPanel                    matlab.ui.container.Panel
        ExportGrid                     matlab.ui.container.GridLayout
        ExportFilesButton              matlab.ui.control.Button
        SettingsTab                    matlab.ui.container.Tab
        SettingsPanel                  matlab.ui.container.Panel
        SettingsGrid                   matlab.ui.container.GridLayout
        SaveAllSettingsButton          matlab.ui.control.Button
        InfoTab                        matlab.ui.container.Tab
        InfoPanel                      matlab.ui.container.Panel
        InfoGrid                       matlab.ui.container.GridLayout
        CTULogo                        matlab.ui.control.Image
        NIMHLogo                       matlab.ui.control.Image
        AuthorEmailHyperlink           matlab.ui.control.Hyperlink
        AppVersion                     matlab.ui.control.Label
        MatlabVersion                  matlab.ui.control.Label
        ApplicationVersionLabel        matlab.ui.control.Label
        MatlabVersionLabel             matlab.ui.control.Label
        OpenSourceCodeButton           matlab.ui.control.Button
        OpenMastersThesisButton        matlab.ui.control.Button
        OpenDocumentationButton        matlab.ui.control.Button
        SupervisorDepartmentLabel      matlab.ui.control.Label
        AuthorDepartmentLabel          matlab.ui.control.Label
        YearLabel                      matlab.ui.control.Label
        AcademicYearLabel              matlab.ui.control.Label
        SupervisorNameLabel            matlab.ui.control.Label
        SupervisorLabel                matlab.ui.control.Label
        AuthorNameLabel                matlab.ui.control.Label
        AuthorLabel                    matlab.ui.control.Label
        Text3Label                     matlab.ui.control.Label
        Text2Label                     matlab.ui.control.Label
        Text1Label                     matlab.ui.control.Label
        DescriptionLabel               matlab.ui.control.Label
        NameOfAppLabel                 matlab.ui.control.Label
        Image                          matlab.ui.control.Image
    end


    properties (Access = private)
        Theme % Description
        ButtonsMenu
        ButtonsOther
        Grids
        Labels

        SingleOrBatchMode
    end
    
    properties (Access = public)
        APP_VERSION = "1.1.2025.21.4"
    end

    methods (Access = private)

        function resetMenuButtonColor(app)
            for b = app.ButtonsMenu
                b.BackgroundColor = app.menuNotActiveColor;
            end
        end

        function applyTheme(app, themeStruct)
            app.Theme = themeStruct;
            fig = app.SqueakPeekStudioUIFigure;
            fig.Color = themeStruct.Background;

            for i = 1:numel(app.ButtonsMenu)
                app.ButtonsMenu(i).BackgroundColor = themeStruct.Background;
                app.ButtonsMenu(i).FontColor = themeStruct.Text;
            end
            
            for i = 1:numel(app.ButtonsOther)
                btn = app.ButtonsOther(i);
                if isstruct(btn.UserData) && isfield(btn.UserData, "Style") && strcmp(btn.UserData.Style, "Primary")
                    btn.BackgroundColor = themeStruct.Primary;
                    btn.FontColor = themeStruct.Text;
                elseif isstruct(btn.UserData) && isfield(btn.UserData, "Style") && strcmp(btn.UserData.Style, "Accent")
                    btn.BackgroundColor = themeStruct.Accent;
                    btn.FontColor = themeStruct.Text;
                else
                    btn.BackgroundColor = themeStruct.Background;
                    btn.FontColor = themeStruct.Text;   
                end
            
            end
        
                    
            for i = 1:numel(app.Grids)
                app.Grids(i).BackgroundColor = themeStruct.Background;
            end
            for i = 1:numel(app.Labels)
                app.Labels(i).FontColor = themeStruct.Text;
            end


            app.ButtonsMenuGrid.BackgroundColor = themeStruct.Primary;
        end


        function InitButtons(app)
            app.DataInputButton.UserData = app.DataInputTab;
            app.VisualizationButton.UserData = app.VisualizationTab;
            app.DetectionButton.UserData = app.DetectionTab;
            app.LabelEditButton.UserData = app.LabelEditTab;
            app.VideoSyncButton.UserData = app.VideoSyncTab;
            app.ExportButton.UserData = app.ExportTab;
            app.SettingsButton.UserData = app.SettingsTab;
            app.InfoButton.UserData = app.InfoTab;

            app.LoadFilesButton.UserData.Style = "Accent";
            app.RunDetectorButton.UserData.Style = "Accent";
            app.ExportFilesButton.UserData.Style = "Accent";
            app.SaveAllSettingsButton.UserData.Style = "Accent";
            app.OpenDocumentationButton.UserData.Style = "Primary";
            app.OpenSourceCodeButton.UserData.Style = "Primary";
            app.OpenMastersThesisButton.UserData.Style = "Accent";
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            disp("Welcome to Squeak Peek Studio by Antonín Gazda");
            fprintf("Version %s\n",app.APP_VERSION);
            app.AppVersion.Text = app.APP_VERSION;
            % define components
            app.ButtonsMenu = [app.DataInputButton, app.VisualizationButton, app.DetectionButton, ...
                app.LabelEditButton, app.VideoSyncButton, app.ExportButton, ...
                app.SettingsButton, app.InfoButton];

            allButtons = findall(app.SqueakPeekStudioUIFigure, 'Type', 'uibutton');
            app.ButtonsOther = setdiff(allButtons, app.ButtonsMenu);

            app.Grids = findall(app.SqueakPeekStudioUIFigure, 'Type', 'uigridlayout');
            app.Labels = findall(app.SqueakPeekStudioUIFigure, 'Type', 'uilabel');

            InitButtons(app);  %initialize UserData
            applyTheme(app, ThemeColors.Light);

            % Select default tab
            app.HandleMenuButtons(struct("Source", app.DataInputButton));

            % Select Single Mode
            app.SingleOrBatchMode = 0;
            app.SingleModePanel.Enable = "on";
            app.BatchModePanel.Enable = "off";
        end

        % Button pushed function: DataInputButton, DetectionButton, 
        % ...and 6 other components
        function HandleMenuButtons(app, event)
            clickedButton = event.Source;
            % Reset all buttons to the inactive color

            for b = app.ButtonsMenu
                b.BackgroundColor = app.Theme.Background;
            end

            % Highlight the clicked button
            clickedButton.BackgroundColor = app.Theme.Accent;

            % Switch to the tab associated with this button
            app.TabGroup.SelectedTab = clickedButton.UserData;
        end

        % Button pushed function: SingleBatchModeButton
        function SingleBatchModeButtonPushed(app, event)
            btn = event.Source;
            lastState = app.SingleOrBatchMode;
            if lastState == 0 % single
                % Switch to Batch mode
                btn.Text = 'Batch Mode';
                btn.BackgroundColor = app.Theme.Accent;
                app.SingleOrBatchMode = 1;

                %change text on labels and the files they accept
                app.SingleModePanel.Enable = "off";
                app.BatchModePanel.Enable = "on";
            else
                % Switch to Single mode
                btn.Text = 'Single Mode';
                btn.BackgroundColor = app.Theme.Background;
               app.SingleOrBatchMode = 0;
               app.SingleModePanel.Enable = "on";
               app.BatchModePanel.Enable = "off";
            end

            
        end

        % Button pushed function: LoadFilesButton
        function LoadFilesButtonPushed(app, event)
            % preload files in single mode
            % what to do in batch mode?

            % automatically switch to visualisation
            app.HandleMenuButtons(struct('Source', app.VisualizationButton));
        end

        % Button pushed function: OpenDocumentationButton
        function OpenDocumentationButtonPushed(app, event)
            web('http://www.antoningazda.com/squeakpeek/docs', '-browser')
        end

        % Button pushed function: OpenMastersThesisButton
        function OpenMastersThesisButtonPushed(app, event)
            web('http://www.antoningazda.com/squeakpeek/thesis', '-browser')
        end

        % Button pushed function: OpenSourceCodeButton
        function OpenSourceCodeButtonPushed(app, event)
            web('http://www.antoningazda.com/squeakpeek/source', '-browser')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.SqueakPeekStudioUIFigure = uifigure('Visible', 'off');
            app.SqueakPeekStudioUIFigure.Name = 'Squeak Peek Studio';
            app.SqueakPeekStudioUIFigure.Icon = fullfile(pathToMLAPP, 'assets', 'logo', 'squeakpeak_logo.png');
            app.TabGroup = uitabgroup(app.SqueakPeekStudioUIFigure);
            app.DataInputTab = uitab(app.TabGroup);
            app.DataInputTab.Title = 'Data Input';
            app.DataInputPanel = uipanel(app.DataInputTab);
            app.DataInputGrid = uigridlayout(app.DataInputPanel);
            app.LoadFilesButton = uibutton(app.DataInputGrid, 'push');
            app.LoadFilesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFilesButtonPushed, true);
            app.LoadFilesButton.Text = 'Load Files';
            app.SingleBatchModeButton = uibutton(app.DataInputGrid, 'push');
            app.SingleBatchModeButton.ButtonPushedFcn = createCallbackFcn(app, @SingleBatchModeButtonPushed, true);
            app.SingleBatchModeButton.Text = 'Single Mode';
            app.SingleModePanel = uipanel(app.DataInputGrid);
            app.SingleModeGridGrid = uigridlayout(app.SingleModePanel);
            app.SelectUSVButtonSingle = uibutton(app.SingleModeGridGrid, 'push');
            app.SelectUSVButtonSingle.Text = 'Select USV (.wav)';
            app.SelectUSVwavEditFieldSingle = uieditfield(app.SingleModeGridGrid, 'text');
            app.SelectLabeltxtButtonSingle = uibutton(app.SingleModeGridGrid, 'push');
            app.SelectLabeltxtButtonSingle.Text = 'Select Label (.txt)';
            app.SelectLabeltxtEditFieldSingle = uieditfield(app.SingleModeGridGrid, 'text');
            app.SelectReferenceLabeltxtEditFieldSingle = uieditfield(app.SingleModeGridGrid, 'text');
            app.SelectVideomp4EditFieldSingle = uieditfield(app.SingleModeGridGrid, 'text');
            app.SelectVideomp4ButtonSingle = uibutton(app.SingleModeGridGrid, 'push');
            app.SelectVideomp4ButtonSingle.Text = 'Select Video (.mp4)';
            app.SelectReferenceLabeltxtButtonSingle = uibutton(app.SingleModeGridGrid, 'push');
            app.SelectReferenceLabeltxtButtonSingle.Text = 'Select Reference Label (.txt)';
            app.BatchModePanel = uipanel(app.DataInputGrid);
            app.BatchModeGridGrid = uigridlayout(app.BatchModePanel);
            app.SelectUSVButtonBatch = uibutton(app.BatchModeGridGrid, 'push');
            app.SelectUSVButtonBatch.Text = 'Select USVs';
            app.SelectLabeltxtButtonBatch = uibutton(app.BatchModeGridGrid, 'push');
            app.SelectLabeltxtButtonBatch.Text = 'Select Labels';
            app.SelectReferenceLabeltxtButtonBatch = uibutton(app.BatchModeGridGrid, 'push');
            app.SelectReferenceLabeltxtButtonBatch.Text = 'Select Reference Labels';
            app.SelectVideomp4ButtonBatch = uibutton(app.BatchModeGridGrid, 'push');
            app.SelectVideomp4ButtonBatch.Text = 'Select Videos';
            app.SelectLabeltxtEditFieldBatch = uieditfield(app.BatchModeGridGrid, 'text');
            app.SelectReferenceLabeltxtEditFieldBatch = uieditfield(app.BatchModeGridGrid, 'text');
            app.SelectVideomp4EditFieldBatch = uieditfield(app.BatchModeGridGrid, 'text');
            app.SelectUSVwavEditFieldBatch = uieditfield(app.BatchModeGridGrid, 'text');
            app.VisualizationTab = uitab(app.TabGroup);
            app.VisualizationTab.Title = 'Visualization';
            app.VisualizationPanel = uipanel(app.VisualizationTab);
            app.VisualizationGrid = uigridlayout(app.VisualizationPanel);
            app.WaveformPlot = uipanel(app.VisualizationGrid);
            app.SingleModeGridGrid_2 = uigridlayout(app.WaveformPlot);
            app.UIAxes = uiaxes(app.SingleModeGridGrid_2);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SpectrogramPlot = uipanel(app.VisualizationGrid);
            app.SingleModeGridGrid_3 = uigridlayout(app.SpectrogramPlot);
            app.UIAxes2 = uiaxes(app.SingleModeGridGrid_3);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.Button = uibutton(app.VisualizationGrid, 'push');
            app.Button.Text = '<';
            app.Button_2 = uibutton(app.VisualizationGrid, 'push');
            app.Button_2.Text = '>';
            app.SonifyButton = uibutton(app.VisualizationGrid, 'push');
            app.SonifyButton.Text = 'Sonify';
            app.SegmentStartsEditFieldLabel = uilabel(app.VisualizationGrid);
            app.SegmentStartsEditFieldLabel.Text = 'Segment Start (s)';
            app.SegmentStartsEditField = uieditfield(app.VisualizationGrid, 'numeric');
            app.SegmentStartsEditField.Value = 99999999;
            app.SegmentLengthsEditFieldLabel = uilabel(app.VisualizationGrid);
            app.SegmentLengthsEditFieldLabel.Text = 'Segment Length (s)';
            app.SegmentLengthsEditField = uieditfield(app.VisualizationGrid, 'numeric');
            app.SegmentLengthsEditField.Value = 99999999;
            app.PlotButton = uibutton(app.VisualizationGrid, 'push');
            app.PlotButton.Text = 'Plot';
            app.DetectionTab = uitab(app.TabGroup);
            app.DetectionTab.Title = 'Detection';
            app.DetectionPanel = uipanel(app.DetectionTab);
            app.DetectionGrid = uigridlayout(app.DetectionPanel);
            app.RunDetectorButton = uibutton(app.DetectionGrid, 'push');
            app.RunDetectorButton.Text = 'Run Detector';
            app.LabelEditTab = uitab(app.TabGroup);
            app.LabelEditTab.Title = 'Label Edit';
            app.LabelEditPanel = uipanel(app.LabelEditTab);
            app.LabelEditGrid = uigridlayout(app.LabelEditPanel);
            app.VideoSyncTab = uitab(app.TabGroup);
            app.VideoSyncTab.Title = 'Video Sync';
            app.VideoSyncPanel = uipanel(app.VideoSyncTab);
            app.VideoSyncGrid = uigridlayout(app.VideoSyncPanel);
            app.VideoSyncLabel = uilabel(app.VideoSyncGrid);
            app.VideoSyncLabel.Text = 'Video Sync';
            app.ExportTab = uitab(app.TabGroup);
            app.ExportTab.Title = 'Export';
            app.ExportPanel = uipanel(app.ExportTab);
            app.ExportGrid = uigridlayout(app.ExportPanel);
            app.ExportFilesButton = uibutton(app.ExportGrid, 'push');
            app.ExportFilesButton.Text = 'Export Files';
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.SettingsPanel = uipanel(app.SettingsTab);
            app.SettingsGrid = uigridlayout(app.SettingsPanel);
            app.SaveAllSettingsButton = uibutton(app.SettingsGrid, 'push');
            app.SaveAllSettingsButton.Text = 'Save All Settings';
            app.InfoTab = uitab(app.TabGroup);
            app.InfoTab.Title = 'Info';
            app.InfoPanel = uipanel(app.InfoTab);
            app.InfoGrid = uigridlayout(app.InfoPanel);
            app.Image = uiimage(app.InfoGrid);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'assets', 'logo', 'squeakpeak_logo.png');
            app.NameOfAppLabel = uilabel(app.InfoGrid);
            app.NameOfAppLabel.Text = 'Squeak Peek Studio';
            app.DescriptionLabel = uilabel(app.InfoGrid);
            app.DescriptionLabel.Text = 'Software for Visualization, Segmentation, and Sonification of Ultrasonic Vocalizations of Laboratory Rats ';
            app.Text1Label = uilabel(app.InfoGrid);
            app.Text1Label.Text = 'This application was developed as part of a Master’s Thesis in Electronics and Communications - Audiovisual Technology and Signal';
            app.Text2Label = uilabel(app.InfoGrid);
            app.Text2Label.Text = 'processing at the Czech Technical University in Prague, Faculty of Electrical Engineering (CTU FEE) in collaboration with National Institute of';
            app.Text3Label = uilabel(app.InfoGrid);
            app.Text3Label.Text = 'Mental Health in Klecany, Czech Republic.';
            app.AuthorLabel = uilabel(app.InfoGrid);
            app.AuthorLabel.Text = 'Author';
            app.AuthorNameLabel = uilabel(app.InfoGrid);
            app.AuthorNameLabel.Text = 'Antonín Gazda';
            app.SupervisorLabel = uilabel(app.InfoGrid);
            app.SupervisorLabel.Text = 'Supervisor';
            app.SupervisorNameLabel = uilabel(app.InfoGrid);
            app.SupervisorNameLabel.Text = 'prof. Ing. Roman Čmejla, CSc.';
            app.AcademicYearLabel = uilabel(app.InfoGrid);
            app.AcademicYearLabel.Text = 'Academic Year';
            app.YearLabel = uilabel(app.InfoGrid);
            app.YearLabel.Text = '2024/2025';
            app.AuthorDepartmentLabel = uilabel(app.InfoGrid);
            app.AuthorDepartmentLabel.Text = 'Department of Radioelectronics';
            app.SupervisorDepartmentLabel = uilabel(app.InfoGrid);
            app.SupervisorDepartmentLabel.Text = 'Department of Circuit Theory';
            app.OpenDocumentationButton = uibutton(app.InfoGrid, 'push');
            app.OpenDocumentationButton.ButtonPushedFcn = createCallbackFcn(app, @OpenDocumentationButtonPushed, true);
            app.OpenDocumentationButton.Text = 'Open Documentation';
            app.OpenMastersThesisButton = uibutton(app.InfoGrid, 'push');
            app.OpenMastersThesisButton.ButtonPushedFcn = createCallbackFcn(app, @OpenMastersThesisButtonPushed, true);
            app.OpenMastersThesisButton.Text = 'Open Master''s Thesis';
            app.OpenSourceCodeButton = uibutton(app.InfoGrid, 'push');
            app.OpenSourceCodeButton.ButtonPushedFcn = createCallbackFcn(app, @OpenSourceCodeButtonPushed, true);
            app.OpenSourceCodeButton.Text = 'Open Source Code';
            app.MatlabVersionLabel = uilabel(app.InfoGrid);
            app.MatlabVersionLabel.Text = 'MATLAB version';
            app.ApplicationVersionLabel = uilabel(app.InfoGrid);
            app.ApplicationVersionLabel.Text = 'Application version';
            app.MatlabVersion = uilabel(app.InfoGrid);
            app.MatlabVersion.Text = '23.2.0.2380103 (R2023b) Update 1';
            app.AppVersion = uilabel(app.InfoGrid);
            app.AppVersion.Text = 'version defined at runtime';
            app.AuthorEmailHyperlink = uihyperlink(app.InfoGrid);
            app.AuthorEmailHyperlink.URL = 'mailto:me@antoningazda.com';
            app.AuthorEmailHyperlink.Text = {'me@antoningazda.com'; ''};
            app.NIMHLogo = uiimage(app.InfoGrid);
            app.NIMHLogo.URL = 'https://www.nudz.cz/';
            app.NIMHLogo.AltText = 'NIMH LOGO';
            app.NIMHLogo.ImageSource = fullfile(pathToMLAPP, 'assets', 'logo', 'nimh_logo.png');
            app.CTULogo = uiimage(app.InfoGrid);
            app.CTULogo.URL = 'https://fel.cvut.cz';
            app.CTULogo.AltText = 'CTU FEE LOGO';
            app.CTULogo.ImageSource = fullfile(pathToMLAPP, 'assets', 'logo', 'fee_logo.jpg');
            app.ButtonsMenuPanel = uipanel(app.SqueakPeekStudioUIFigure);
            app.ButtonsMenuGrid = uigridlayout(app.ButtonsMenuPanel);
            app.DataInputButton = uibutton(app.ButtonsMenuGrid, 'push');
            app.DataInputButton.ButtonPushedFcn = createCallbackFcn(app, @HandleMenuButtons, true);
            app.DataInputButton.Text = 'Data Input';
            app.VisualizationButton = uibutton(app.ButtonsMenuGrid, 'push');
            app.VisualizationButton.ButtonPushedFcn = createCallbackFcn(app, @HandleMenuButtons, true);
            app.VisualizationButton.Text = 'Visualization';
            app.DetectionButton = uibutton(app.ButtonsMenuGrid, 'push');
            app.DetectionButton.ButtonPushedFcn = createCallbackFcn(app, @HandleMenuButtons, true);
            app.DetectionButton.Text = 'Detection';
            app.LabelEditButton = uibutton(app.ButtonsMenuGrid, 'push');
            app.LabelEditButton.ButtonPushedFcn = createCallbackFcn(app, @HandleMenuButtons, true);
            app.LabelEditButton.Text = 'Label Edit';
            app.VideoSyncButton = uibutton(app.ButtonsMenuGrid, 'push');
            app.VideoSyncButton.ButtonPushedFcn = createCallbackFcn(app, @HandleMenuButtons, true);
            app.VideoSyncButton.Text = 'Video Sync';
            app.ExportButton = uibutton(app.ButtonsMenuGrid, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @HandleMenuButtons, true);
            app.ExportButton.Text = 'Export';
            app.SettingsButton = uibutton(app.ButtonsMenuGrid, 'push');
            app.SettingsButton.ButtonPushedFcn = createCallbackFcn(app, @HandleMenuButtons, true);
            app.SettingsButton.Text = 'Settings';
            app.InfoButton = uibutton(app.ButtonsMenuGrid, 'push');
            app.InfoButton.ButtonPushedFcn = createCallbackFcn(app, @HandleMenuButtons, true);
            app.InfoButton.Text = 'Info';
            % Show the figure after all components are created
            app.SqueakPeekStudioUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SqueakPeekStudio

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SqueakPeekStudioUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SqueakPeekStudioUIFigure)
        end
    end
end